classdef Battleship_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BattleshipGUIUIFigure         matlab.ui.Figure
        UpButton                      matlab.ui.control.Button
        RightButton                   matlab.ui.control.Button
        DownButton                    matlab.ui.control.Button
        LeftButton                    matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        ShipsRemainingEditFieldLabel  matlab.ui.control.Label
        ShipsRemainingEditField       matlab.ui.control.NumericEditField
        AttemptsRemainingEditFieldLabel  matlab.ui.control.Label
        AttemptsRemainingEditField    matlab.ui.control.NumericEditField
        StartButton                   matlab.ui.control.Button
        QuitButton                    matlab.ui.control.Button
    end


    properties (Access = private)
        board % LED location(s)
        ard % arduino object
        pins = app.Adi_pins% pin configuration
    end

    methods (Access = private)
    
        function updateBoard(app)
            for c = 1:9
                writeDigitalPin(app.ard, app.pins(c), app.board(c))
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % first we establish connection and clear all existing variables
            clc, clear
            app.ard = arduino();
            
            % define pin configurations
            Adi_pins = ["D12", "D11", "D10";
                        "D8", "D7", "D6";
                        "D4", "D3", "D2"]; % Adi's pin configuration
                
            Vincent_pins = ["D2", "D3", "D4";
                                "D7", "D8", "D9";
                                "D11", "D12", "D13"]; % Vincent's pin configuration
        end

        % Button pushed function: UpButton
        function UpButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if row - 1 < 1
                % move not allowed - do nothing
            else
                app.board(row - 1, col) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if col + 1 > 3
                % move not allowed - do nothing
            else
                app.board(row, col + 1) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end

        % Button pushed function: DownButton
        function DownButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if row + 1 > 3
                % move not allowed - do nothing
            else
                app.board(row + 1, col) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            uiresume % resumes program
            [row, col] = find(app.board == 1);
            if col - 1 < 1
                % move not allowed - do nothing
            else
                app.board(row, col - 1) = 1; % update new LED
                app.board(row, col) = 0; % erase old LED
                updateBoard(app)
            end
            uiwait % pauses program
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BattleshipGUIUIFigure = uifigure;
            app.BattleshipGUIUIFigure.Name = 'Battleship GUI';
            app.UpButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.UpButton.ButtonPushedFcn = createCallbackFcn(app, @UpButtonPushed, true);
            app.UpButton.Text = 'Up';
            app.RightButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.DownButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.DownButton.ButtonPushedFcn = createCallbackFcn(app, @DownButtonPushed, true);
            app.DownButton.Text = 'Down';
            app.LeftButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.SelectButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.SelectButton.Text = 'Select';
            app.ShipsRemainingEditFieldLabel = uilabel(app.BattleshipGUIUIFigure);
            app.ShipsRemainingEditFieldLabel.Text = 'Ships Remaining';
            app.ShipsRemainingEditField = uieditfield(app.BattleshipGUIUIFigure, 'numeric');
            app.AttemptsRemainingEditFieldLabel = uilabel(app.BattleshipGUIUIFigure);
            app.AttemptsRemainingEditFieldLabel.Text = 'Attempts Remaining';
            app.AttemptsRemainingEditField = uieditfield(app.BattleshipGUIUIFigure, 'numeric');
            app.StartButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.StartButton.Text = 'Start';
            app.QuitButton = uibutton(app.BattleshipGUIUIFigure, 'push');
            app.QuitButton.Text = 'Quit';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Battleship_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BattleshipGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BattleshipGUIUIFigure)
        end
    end
end